classdef SingleCSVConcatenatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectButton                  matlab.ui.control.Button
        WorkingDirEditFieldLabel      matlab.ui.control.Label
        WorkingDirEditField           matlab.ui.control.EditField
        IncludedCSVFilesListBoxLabel  matlab.ui.control.Label
        IncludedCSVFilesListBox       matlab.ui.control.ListBox
        SelectOutputDirButton         matlab.ui.control.Button
        OutputDirEditFieldLabel       matlab.ui.control.Label
        OutputDirEditField            matlab.ui.control.EditField
        OutputFilenameEditFieldLabel  matlab.ui.control.Label
        OutputFilenameEditField       matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        selectedFileNames % The user selected .CSV files
        selectedFilePaths % The user selected .CSV files
        selectedFileParentPaths % The user-selected .CSV file's parent path
        outputDir % Description
        outFileName
        numHeaderLines % Description
    end
    
    properties (Access = private)
        finalOutputFilePath % Description
        headerLines % Description
        combinedBodyLines % Description
    end
    
    methods (Access = private)
        
        function funcProcessCSVFiles(app)
            %% Process CSV files
            app.numHeaderLines = 4;
            app.headerLines = cell(app.numHeaderLines,1);

            % Setup the combined output data
            app.combinedBodyLines = cell(0,1);

            for fileIndex = 1:length(app.selectedFilePaths)
                % Construct the full file path
                currCSVFilePath = app.selectedFilePaths{fileIndex};
                fid = fopen(currCSVFilePath,'r');
                % Get the header information from the first file
                if (fileIndex == 1)
                    for lineIndex = 1:app.numHeaderLines
                     app.headerLines(lineIndex) = {fgetl(fid)}; 
                    end
                    % Process the header information
                    %TODO: verify that the header info hasn't changed between files (so
                    %we don't concatinate differnt data
                else
                    %read the header lines for non-first files, but throw them away.
                    for lineIndex = 1:app.numHeaderLines
                        trash = {fgetl(fid)}; 
                    end
                end
            
                tline = fgetl(fid);
                while ischar(tline)
                    app.combinedBodyLines{end+1,1} = tline;
                    tline = fgetl(fid);
                end
            
                fclose(fid);
            end
            
            
            % Save out to new csv file
            fid = fopen(app.finalOutputFilePath,'w');
            for lineIndex = 1:app.numHeaderLines
                fprintf(fid,'%s\n',app.headerLines{lineIndex});
            end
            % Write body lines
            for lineIndex = 1:length(app.combinedBodyLines)
                fprintf(fid,'%s\n',app.combinedBodyLines{lineIndex});
            end
            fclose(fid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [fileName, parentPath, filterindex] = uigetfile('*.csv', 'Select a the first .csv file to merge', 'MultiSelect', 'off');
            filePath = fullfile(parentPath,fileName);
            
            app.selectedFileNames{end+1} = fileName;
            app.selectedFileParentPaths{end+1} = parentPath;
            app.selectedFilePaths{end+1} = filePath;
            
            app.IncludedCSVFilesListBox.Items = app.selectedFileNames;
            
        end

        % Button pushed function: SelectOutputDirButton
        function SelectOutputDirButtonPushed(app, event)
            % Called to change the output directory
            %app.outputDir = uigetdir(app.selectedFileParentPaths{1},'Select an output path:');
            app.outputDir = uigetdir('Select an output path:');
            app.OutputDirEditField.Value = app.outputDir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Save button pressed
            app.outFileName = app.OutputFilenameEditField.Value;
            app.finalOutputFilePath = fullfile(app.outputDir, app.outFileName);
            
            app.funcProcessCSVFiles();
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select...';
            app.WorkingDirEditFieldLabel = uilabel(app.UIFigure);
            app.WorkingDirEditFieldLabel.Text = 'Working Dir';
            app.WorkingDirEditField = uieditfield(app.UIFigure, 'text');
            app.IncludedCSVFilesListBoxLabel = uilabel(app.UIFigure);
            app.IncludedCSVFilesListBoxLabel.Text = 'Included .CSV Files';
            app.IncludedCSVFilesListBox = uilistbox(app.UIFigure);
            app.IncludedCSVFilesListBox.Items = {};
            app.IncludedCSVFilesListBox.Value = {};
            app.SelectOutputDirButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirButtonPushed, true);
            app.SelectOutputDirButton.Text = 'Select...';
            app.OutputDirEditFieldLabel = uilabel(app.UIFigure);
            app.OutputDirEditFieldLabel.Text = 'Output Dir';
            app.OutputDirEditField = uieditfield(app.UIFigure, 'text');
            app.OutputFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputFilenameEditField.Value = 'CombinedOutput.csv';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleCSVConcatenatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end