classdef PhoPendingBackgroundExtractor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhoVideoBackgroundExtractorUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenSourceVideoMenu             matlab.ui.container.Menu
        ExportExtractedFramesMenu       matlab.ui.container.Menu
        ExportGeneratedBackgroundAsMenu  matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        ClearAllExtractedFramesMenu     matlab.ui.container.Menu
        RefreshGeneratedBackgroundMenu  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        SliderLabel                     matlab.ui.control.Label
        SliderPlayback                  matlab.ui.control.Slider
        GrabButton                      matlab.ui.control.Button
        PlayPauseButton                 matlab.ui.control.StateButton
        InstructionsTextAreaLabel       matlab.ui.control.Label
        InstructionsTextArea            matlab.ui.control.TextArea
        ChooseButton                    matlab.ui.control.Button
        OpenedVideoLabel                matlab.ui.control.Label
        NoSourceVideoLabel              matlab.ui.control.Label
        STATIC_TotalFramesLabel         matlab.ui.control.Label
        CurrFrameEditField              matlab.ui.control.NumericEditField
        TotalFramesLabel                matlab.ui.control.Label
        STATIC_currFrameLabel           matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        ExtractedFramesPanel            matlab.ui.container.Panel
        GridLayoutExtractedFrames       matlab.ui.container.GridLayout
        ExtractedImageA                 matlab.ui.control.Image
        ExtractedImageB                 matlab.ui.control.Image
        ExtractedImageC                 matlab.ui.control.Image
        ExtractedImageD                 matlab.ui.control.Image
        FrameOptionsPanelA              matlab.ui.container.Panel
        GridLayoutA                     matlab.ui.container.GridLayout
        UpButtonA                       matlab.ui.control.Button
        DownButtonA                     matlab.ui.control.Button
        RemoveButtonA                   matlab.ui.control.Button
        ExportButtonA                   matlab.ui.control.Button
        STATIC_FrameLabelA              matlab.ui.control.Label
        FrameLabelA                     matlab.ui.control.Label
        STATIC_SourceLabelA             matlab.ui.control.Label
        SourceLabelA                    matlab.ui.control.Label
        FrameOptionsPanelB              matlab.ui.container.Panel
        GridLayoutB                     matlab.ui.container.GridLayout
        UpButtonB                       matlab.ui.control.Button
        DownButtonB                     matlab.ui.control.Button
        RemoveButtonB                   matlab.ui.control.Button
        ExportButtonB                   matlab.ui.control.Button
        STATIC_FrameLabelB              matlab.ui.control.Label
        FrameLabelB                     matlab.ui.control.Label
        STATIC_SourceLabelB             matlab.ui.control.Label
        SourceLabelB                    matlab.ui.control.Label
        FrameOptionsPanelC              matlab.ui.container.Panel
        GridLayoutC                     matlab.ui.container.GridLayout
        UpButtonC                       matlab.ui.control.Button
        DownButtonC                     matlab.ui.control.Button
        RemoveButtonC                   matlab.ui.control.Button
        ExportButtonC                   matlab.ui.control.Button
        STATIC_FrameLabelC              matlab.ui.control.Label
        FrameLabelC                     matlab.ui.control.Label
        STATIC_SourceLabelC             matlab.ui.control.Label
        SourceLabelC                    matlab.ui.control.Label
        FrameOptionsPanelD              matlab.ui.container.Panel
        GridLayoutD                     matlab.ui.container.GridLayout
        UpButtonD                       matlab.ui.control.Button
        DownButtonD                     matlab.ui.control.Button
        RemoveButtonD                   matlab.ui.control.Button
        ExportButtonD                   matlab.ui.control.Button
        STATIC_FrameLabelD              matlab.ui.control.Label
        FrameLabelD                     matlab.ui.control.Label
        STATIC_SourceLabelD             matlab.ui.control.Label
        SourceLabelD                    matlab.ui.control.Label
        LabelNoSelectedFrames           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ImageFinalBackground            matlab.ui.control.Image
        RefreshButton                   matlab.ui.control.Button
        ExportAsButton                  matlab.ui.control.Button
        BackgroundExportPathTextAreaLabel  matlab.ui.control.Label
        BackgroundExportPathTextArea    matlab.ui.control.TextArea
        ChooseExportPathButton          matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
%%%+S- pbeExtractedFrame
	%- image - image is the greyscale image extracted
	%- frameIndex - frameIndex contains the integer frame number it was extracted from, or -1 if it doesn't have one
	%- sourceName - sourceName contains the basename of the video it was extracted from, or '' otherwise.
%
    properties (Access = public)
        SourceVideo % Description
        pbeExtractedFramesList % Description
        inter_frame_change_min_change = 10 % Description
        IntermediateProcessedResultsList % A list of the pairwise background removals for each set of frames.
        bbvaVideoFile % The open source video file
        currentlyPreviewedVideo_pbeExtractedFrame % Description
        bbvaCurrFrameSegment % Description
        currentExportParentPath = '' % Description
        currentExportFileName = ''
        currentExtractedFramesExportParentPath = '' % The location to save extracted frames.
    end
    
    properties (Access = private)
        isModalLoading = false; % Description
    end
    
    methods (Access = private)
        
        function results = loadSampleImages(app)
            
            % '/Volumes/Speakhard/Temp/Videos/BB02/SAMPLED/BehavioralBox_B02_T20200221-1129190334_SAMPLED_SHORT.mp4'
            curr_video_file_path = '/Volumes/Speakhard/Temp/Videos/BB02/BehavioralBox_B02_T20200212-0035110927.mp4';
            
            % The output path to read from:
            curr_output_parent_path = '/Volumes/Speakhard/Temp/MouseBBVideos/Output/';
            curr_output_relative_path = 'BackgroundExtraction/';
            curr_output_relative_name = 'processed_output_BehavioralBox_B02_T20200212-0035110927_Background.mat';
            curr_output_bbvaSettings.final_output_path = fullfile(curr_output_parent_path, curr_output_relative_path);
            curr_output_bbvaSettings.final_output_data_path = fullfile(curr_output_bbvaSettings.final_output_path, curr_output_relative_name);
            
            if ~exist(curr_output_bbvaSettings.final_output_path, 'dir')
                fprintf('Making dir %s...\n', curr_output_bbvaSettings.final_output_path);
%                 mkdir(curr_output_bbvaSettings.final_output_path)
            end
            
            should_print_status = false;
            
            app.bbvaVideoFile.full_path = curr_video_file_path;
%             bbvaVideoFile.v = VideoReader(bbvaVideoFile.full_path);
            
            %% Reference Video Frames extracted as images where the mouse is in different places.
            sample_frames_parent_path = '/Users/pho/repo/PhoMatlabBBVideoAnalyzer/MAIN/HomeCageScanMultiplexing/SampleVideoFrames/';
            % sample_frames_filenames = {'vlcsnap-2020-06-10-14h55m46s868.png','vlcsnap-2020-06-10-14h55m42s729.png','vlcsnap-2020-06-10-14h55m20s404.png','vlcsnap-2020-06-10-14h54m54s982.png','vlcsnap-2020-06-10-14h54m21s878.png'};
            sample_frames_filenames = {'vlcsnap-2020-06-10-14h54m21s878.png', 'vlcsnap-2020-06-10-14h54m54s982.png', 'vlcsnap-2020-06-10-14h55m42s729.png'};
            for i=1:length(sample_frames_filenames)
                sample_frames_pathnames{i} = fullfile(sample_frames_parent_path, sample_frames_filenames{i});
            end
            
            sampleVideoFramesDatastore = imageDatastore(sample_frames_pathnames);
            imageFrames = readall(sampleVideoFramesDatastore);
            app.onLoadImageFrames(imageFrames);
            
        end
        
        function setup(app)
            % Called on app startup:
            addpath(genpath('../../Helpers'));
%             app.loadSampleImages();
            app.refreshInterfaceInteractability();

        end
        
        function exportPath = tryGetExtractedFramesExportPath(app, forceOverride)
            if ~exist('forceOverride','var')
                forceOverride = false;
            end
            % If the path isn't currently set, open an interactive dialog to allow the user to select one.
            if (forceOverride | isempty(app.currentExtractedFramesExportParentPath))
                % Interactive Saving of image:
                app.actionBeginLoading();
                fileParentPath = uigetdir('', 'Select a directory to output the extracted frames to for this session');
                drawnow;
                figure(app.PhoVideoBackgroundExtractorUIFigure)
                
                user_canceled = (fileParentPath == 0);
                if ~user_canceled
                    fprintf('User chose %s as extracted frames export parent path.\n', fileParentPath)
                    app.currentExtractedFramesExportParentPath = fileParentPath;
                else
                    fprintf('User canceled\n')
                    app.actionEndLoading();
                end

            end
            % Set the output variable
            exportPath = app.currentExtractedFramesExportParentPath;
        end


        function computeIntermediateResults(app)
            % Clear the list
            app.IntermediateProcessedResultsList = {};
            % Order matters, and no mice should appear in any of the intermediate frameResults.
            % TODO: make dynamic:
            numExtractedFrames = length(app.pbeExtractedFramesList);
            if numExtractedFrames < 1
                % It's zero, there's nothing to be done. Clear the output.
                fprintf('Warning: zero extracted frames.\n')
            elseif numExtractedFrames == 1
                % It's zero or one, so the only result that can be computed is using a single image. Maybe they chose a already viable background?
                [app.IntermediateProcessedResultsList{end+1}] = app.pbeExtractedFramesList{1}.image;
            else
                % It's at least 2
                frameA = imcomplement(app.pbeExtractedFramesList{1}.image);
                frameB = imcomplement(app.pbeExtractedFramesList{2}.image);
                [app.IntermediateProcessedResultsList{end+1}] = FnRemoveChangingObjects(frameA, frameB, app.inter_frame_change_min_change);
                
                if numExtractedFrames >= 3
                    frameC = imcomplement(app.pbeExtractedFramesList{3}.image);
               
                    [app.IntermediateProcessedResultsList{end+1}] = FnRemoveChangingObjects(frameA, frameC, app.inter_frame_change_min_change);
                    [app.IntermediateProcessedResultsList{end+1}] = FnRemoveChangingObjects(frameB, frameC, app.inter_frame_change_min_change);

                end
                
                if numExtractedFrames >= 4                    
                    % frameD = imcomplement(app.pbeExtractedFramesList{5});     
                    %% TODO:
                    fprintf('Warning, more than 3 frames is not yet supported! Using the first 3 extracted frames.\n')
                    
                end
                
            end

        end
        
        
        % Helper builds a pbeExtractedFrame object like thoe stored in app.pbeExtractedFramesList out of the provided items.
        function pbeExtractedFrame = build_pbeExtractedFrame(app, imageFrame, extractedSourceFile, extractedIndex)
            %%%+S- pbeExtractedFrame
            	%- image - image is the greyscale image extracted
            	%- frameIndex - frameIndex contains the integer frame number it was extracted from, or -1 if it doesn't have one
            	%- sourceName - sourceName contains the basename of the video it was extracted from, or '' otherwise.
            %
            if ~exist('extractedIndex','var')
                extractedIndex = -1;
            end
            
            if ~exist('extractedSourceFile','var')
                extractedSourceFile = '';
            end
            pbeExtractedFrame.image = imageFrame;
            pbeExtractedFrame.sourceName = extractedSourceFile;
            pbeExtractedFrame.frameIndex = extractedIndex;
        end
        
                % Called to update the appropriate clickability of the controls and such given the program's status
        function refreshInterfaceInteractability(app)
            
            %% Source Video:
            hasLoadedSourceVideo = ~isempty(app.currentlyPreviewedVideo_pbeExtractedFrame);

            %% Extracted Video frames:
            hasExtractedFrames = ~isempty(app.pbeExtractedFramesList); % this list doesn't seem to be empty to start
            
            %% Output Video:
            %hasValidGeneratedFinalBackgroundImage = (app.ImageFinalBackground.ImageSource ~= '');
            hasValidGeneratedFinalBackgroundImage = ~isempty(app.ImageFinalBackground.ImageSource);
            
            %% Export Parameters:
            hasValidExportPath = ~isempty(app.currentExportFileName);
            %hasValidExportPath = isempty(currExportFullpath);
            
            %% Setup:
            %app.CenterPanel.Enable = app.getOnOffValue(~app.isModalLoading);
            
            app.OpenSourceVideoMenu.Enable = app.getOnOffValue(~app.isModalLoading);
            app.ChooseButton.Enable = app.getOnOffValue(~app.isModalLoading);
            
            app.Image.Enable = app.getOnOffValue(hasLoadedSourceVideo & ~app.isModalLoading);
            app.NoSourceVideoLabel.Visible = (~hasLoadedSourceVideo); % Show even when loading, because it displays the "Loading..." message
            
            app.SliderPlayback.Enable = app.getOnOffValue(hasLoadedSourceVideo & ~app.isModalLoading);
            app.GrabButton.Enable = app.getOnOffValue(hasLoadedSourceVideo & ~app.isModalLoading);
            
            % Hide the current edit field and total frames label completely if needed
            app.CurrFrameEditField.Visible = app.getOnOffValue(hasLoadedSourceVideo);
            app.STATIC_currFrameLabel.Visible = app.getOnOffValue(hasLoadedSourceVideo);
            app.STATIC_TotalFramesLabel.Visible = app.getOnOffValue(hasLoadedSourceVideo);
            app.TotalFramesLabel.Visible = app.getOnOffValue(hasLoadedSourceVideo);
            
            app.CurrFrameEditField.Enable = app.getOnOffValue(hasLoadedSourceVideo & ~app.isModalLoading);
            app.TotalFramesLabel.Enable = app.getOnOffValue(hasLoadedSourceVideo & ~app.isModalLoading);
            
            app.LabelNoSelectedFrames.Visible = (~hasExtractedFrames & ~app.isModalLoading);
            app.ExportExtractedFramesMenu.Enable = app.getOnOffValue(hasExtractedFrames & ~app.isModalLoading);
            app.RefreshButton.Enable = app.getOnOffValue(hasExtractedFrames & ~app.isModalLoading);
            app.RefreshGeneratedBackgroundMenu.Enable = app.getOnOffValue(hasExtractedFrames & ~app.isModalLoading);
            
            app.ChooseExportPathButton.Enable = app.getOnOffValue(~app.isModalLoading);
            
            app.ExportButton.Enable = app.getOnOffValue((hasValidExportPath & hasValidGeneratedFinalBackgroundImage) & ~app.isModalLoading);
            app.ExportAsButton.Enable = app.getOnOffValue(hasValidGeneratedFinalBackgroundImage & ~app.isModalLoading);
            app.ExportGeneratedBackgroundAsMenu.Enable = app.getOnOffValue(hasValidGeneratedFinalBackgroundImage & ~app.isModalLoading);
            app.ImageFinalBackground.Enable = app.getOnOffValue(hasValidGeneratedFinalBackgroundImage & ~app.isModalLoading);
            
        end
        

        function onLoadImageFrames(app, imagesList)
            %only called to load the sample frames, or to clear the frames, so doesn't need to inclue build 
            app.pbeExtractedFramesList = cell(length(imagesList),1);
            for i = 1:length(imagesList)
                app.pbeExtractedFramesList{i} = app.build_pbeExtractedFrame(rgb2gray(imagesList{i}));
            end
            app.reloadExtractedFramesList();
            app.recomputeOutputImage();
            app.refreshInterfaceInteractability();
        end
        
        
        function onLoadAdditionalImageFrame(app, curr_pbeExtractedFrame)
            % Appends the newly loaded imageFrame to the existing image frames
            curr_pbeExtractedFrame = app.build_pbeExtractedFrame(rgb2gray(curr_pbeExtractedFrame.image), curr_pbeExtractedFrame.sourceName, curr_pbeExtractedFrame.frameIndex);
            app.pbeExtractedFramesList{end+1} = curr_pbeExtractedFrame;
            app.reloadExtractedFramesList();
            app.recomputeOutputImage();
            app.refreshInterfaceInteractability();
        end
                
        
        % Called when a new source video is opened
        function onOpenSourceVideo(app, fileParentPath, fileName)
    
            % This is the command that takes a while.     
            app.NoSourceVideoLabel.Text = 'Loading...';
            app.NoSourceVideoLabel.Tooltip = 'Loading Video. Please wait.';
            app.OpenedVideoLabel.Text = 'Loading...';
            app.OpenedVideoLabel.Tooltip = 'Loading Video. Please wait.';
            app.actionBeginLoading();
            
            [app.bbvaVideoFile, app.bbvaCurrFrameSegment] = BuildVideoFileReaderStructure(fileParentPath, fileName);
            
            app.OpenedVideoLabel.Text = app.bbvaVideoFile.basename;
            app.OpenedVideoLabel.Tooltip = app.bbvaVideoFile.full_path;
            app.bbvaVideoFile.v = VideoReader(app.bbvaVideoFile.full_path);
            
            % Reset the slider:
            app.SliderPlayback.Value = 1;
            app.TotalFramesLabel.Text = num2str(app.bbvaCurrFrameSegment.endFrameIndex);
            
            % Load the first frame, and render it in the preview.
            app.actionSeekVideoPosition();
            
            % Update the Interface:
            app.actionEndLoading();
        end
        
        % Changes the output export path
        function onChangeExportPath(app, exportParentPath, exportFileName)
            if ~exist(exportParentPath,'dir')
                mkdir(exportParentPath); % make the export path if it doesn't exist
            end
            app.currentExportParentPath = exportParentPath;
            app.currentExportFileName = exportFileName;
            
            currExportFullpath = fullfile(app.currentExportParentPath, app.currentExportFileName);
            app.BackgroundExportPathTextArea.Value = currExportFullpath;
            
            % Update the Interface:
            app.refreshInterfaceInteractability();
        end
        

        % Converts a boolean input into a string like 'on'/'off', which is used by the .Enable property of UI controls.
        function onOffValue = getOnOffValue(app, booleanValue)
            if booleanValue
                onOffValue = 'on';
            else
                onOffValue = 'off';
            end
        end
        
        function actionBeginLoading(app)
            app.isModalLoading = true;
            app.refreshInterfaceInteractability();
        end
        
        function actionEndLoading(app)
            app.isModalLoading = false;
            app.refreshInterfaceInteractability();
        end
    end
    
    methods (Access = public)
        
        function reloadExtractedFramesList(app)
            extractedImageControlsList = {app.ExtractedImageA, app.ExtractedImageB, app.ExtractedImageC, app.ExtractedImageD};
            extractedFrameOptionsPanelControlsList = {app.FrameOptionsPanelA, app.FrameOptionsPanelB, app.FrameOptionsPanelC, app.FrameOptionsPanelD};
            
            extractedUpButtonControlsList = {app.UpButtonA, app.UpButtonB, app.UpButtonC, app.UpButtonD};
            extractedDownButtonControlsList = {app.DownButtonA, app.DownButtonB, app.DownButtonC, app.DownButtonD};
             
            extractedExportButtonControlsList = {app.ExportButtonA, app.ExportButtonB, app.ExportButtonC, app.ExportButtonD};
            extractedRemoveButtonControlsList = {app.RemoveButtonA, app.RemoveButtonB, app.RemoveButtonC, app.RemoveButtonD};
            
            extractedFrameLabelControlsList = {app.FrameLabelA, app.FrameLabelB, app.FrameLabelC, app.FrameLabelD};
            extractedSourceLabelControlsList = {app.SourceLabelA, app.SourceLabelB, app.SourceLabelC, app.SourceLabelD};
            extractedSourceStaticLabelControlsList = {app.STATIC_SourceLabelA, app.STATIC_SourceLabelB, app.STATIC_SourceLabelC, app.STATIC_SourceLabelD};
            
            numTotalExtractedImageControls = length(extractedImageControlsList);
            numExtractedImages = length(app.pbeExtractedFramesList);
            
            for i=1:numExtractedImages
                extractedImageControlsList{i}.ImageSource = gray2rgb(app.pbeExtractedFramesList{i}.image); % convert to RGB for display in image component
                extractedImageControlsList{i}.Visible = true;
                extractedFrameOptionsPanelControlsList{i}.Visible = true;
                
                % Up/Down Button:
                extractedUpButtonControlsList{i}.Enable = ((i-1) > 0);
                extractedDownButtonControlsList{i}.Enable = (i+1 <= numExtractedImages);
                
                % Extracted Frame Info Label:
                if app.pbeExtractedFramesList{i}.frameIndex > -1
                    extractedFrameLabelControlsList{i}.Text = num2str(app.pbeExtractedFramesList{i}.frameIndex);
                else
                    extractedFrameLabelControlsList{i}.Text = '--';
                end
                
                if ~isempty(app.pbeExtractedFramesList{i}.sourceName)
%                     extractedSourceLabelControlsList{i}.Text = app.pbeExtractedFramesList{i}.sourceName;
                    extractedSourceLabelControlsList{i}.Tooltip = app.pbeExtractedFramesList{i}.sourceName;
                    extractedSourceStaticLabelControlsList{i}.Tooltip = app.pbeExtractedFramesList{i}.sourceName;
                else
%                     extractedSourceLabelControlsList{i}.Text = '--';
                    extractedSourceLabelControlsList{i}.Tooltip = 'No source';
                    extractedSourceStaticLabelControlsList{i}.Tooltip = 'No source';
                end
                
                
                app.GridLayoutExtractedFrames.RowHeight{i} = '1x';
            end
            
            % Maybe introducing bug here by adding +1?
            if numExtractedImages < numTotalExtractedImageControls
                for i=numExtractedImages+1:numTotalExtractedImageControls
                    % Clear the image
%                     extractedImageControlsList{i}.ImageSource = 
                    extractedImageControlsList{i}.Visible = false;
                    extractedFrameOptionsPanelControlsList{i}.Visible = false;
                    
                    % Up/Down Button:
                    extractedUpButtonControlsList{i}.Enable = ((i-1) > 0);
                    extractedDownButtonControlsList{i}.Enable = (i+1 <= numExtractedImages);
                
                    
                    app.GridLayoutExtractedFrames.RowHeight{i} = '0x';
                end
            end

        end
        
        function finalResultFrame = recomputeOutputImage(app)
            app.computeIntermediateResults();
            numIntermediateResults = length(app.IntermediateProcessedResultsList);
            if numIntermediateResults < 1
                [finalResultFrame] = [];
                app.ImageFinalBackground.ImageSource = ''; % convert to RGB for display in image component
            else
                [finalResultFrame] = FnComputeAverageFrame(app.IntermediateProcessedResultsList);
                app.ImageFinalBackground.ImageSource = gray2rgb(finalResultFrame); % convert to RGB for display in image component
            end
            
        end
  
        function actionSeekVideoPosition(app)
            % Reads the frame from the current video position based on the slider, and updates the current preview image.
            % Updates app.currentlyPreviewedVideo_pbeExtractedFrame
            currentVideoFrameIndex = app.getCurrentVideoFrameIndex();
            if (currentVideoFrameIndex > 0)
                % Read the current frame in
                app.currentlyPreviewedVideo_pbeExtractedFrame = app.build_pbeExtractedFrame(read(app.bbvaVideoFile.v, currentVideoFrameIndex), app.bbvaVideoFile.basename, currentVideoFrameIndex);
                app.Image.ImageSource = app.currentlyPreviewedVideo_pbeExtractedFrame.image;
                app.CurrFrameEditField.Value = double(currentVideoFrameIndex); % Update the value, hopefully without triggering the update function?
            else
                app.currentlyPreviewedVideo_pbeExtractedFrame = [];
                app.Image.ImageSource = '';
            end
            % Update the Interface:
            app.refreshInterfaceInteractability();
        end
        
        function actionInteractiveOpenVideo(app)
            % Opens a file chooser to allow the user to select a video
            % Interactive Saving of image:
            app.actionBeginLoading();
            
            [fileName, fileParentPath] = uigetfile('*.mp4','Select a source video');
            drawnow;
            figure(app.PhoVideoBackgroundExtractorUIFigure)
            
            user_canceled = (fileName == 0);
            if ~user_canceled
                
                fprintf('User chose file: %s in parent path: %s.\n', fileName, fileParentPath)
                app.onOpenSourceVideo(fileParentPath, fileName)
            
            else
                fprintf('User canceled\n')
                app.actionEndLoading();
            end
        end
        
        function currentVideoFrameIndex = getCurrentVideoFrameIndex(app)
            % Gets the current video frame index from the slider
            sliderPercentOffset = double(app.SliderPlayback.Value)/100.0;
            fprintf('\t sliderPercentOffset: %f\n', sliderPercentOffset)
            
            curr_video_file = app.bbvaVideoFile;
            if exist('curr_video_file','var')
                curr_video_num_frames = app.bbvaCurrFrameSegment.selectedNumberOfFrames;
                frame_offset = sliderPercentOffset * double(curr_video_num_frames);
                % Seek video to 
                currentVideoFrameIndex = uint64(frame_offset);
                if currentVideoFrameIndex < 1
                    currentVideoFrameIndex = 1; 
                end
                fprintf('\t currentVideoFrameIndex: %d\n', currentVideoFrameIndex)
            else
                fprintf('WARNING: no app.bbvaVideoFile! Cannot get current frame!\n');
                currentVideoFrameIndex = -1;
            end
            
            
        end
        
        function actionGrabCurrentVideoFrame(app)
            if ~isempty(app.currentlyPreviewedVideo_pbeExtractedFrame)
                app.onLoadAdditionalImageFrame(app.currentlyPreviewedVideo_pbeExtractedFrame);
                
            end 
        end
        
        % Moves the image at index imageIndex down to imageIndex + 1.
        function actionMoveImageDown(app, imageIndex)
            destImageIndex = imageIndex + 1;
            movingUpTemp = app.pbeExtractedFramesList{destImageIndex};
            app.pbeExtractedFramesList{destImageIndex} = app.pbeExtractedFramesList{imageIndex};
            app.pbeExtractedFramesList{imageIndex} = movingUpTemp;
            % Refresh the list and such after the move:
            app.reloadExtractedFramesList();
            app.recomputeOutputImage();
        end
        
        % Moves the image at index imageIndex up to imageIndex - 1.
        function actionMoveImageUp(app, imageIndex)
            destImageIndex = imageIndex - 1;
            movingDownTemp = app.pbeExtractedFramesList{destImageIndex};
            app.pbeExtractedFramesList{destImageIndex} = app.pbeExtractedFramesList{imageIndex};
            app.pbeExtractedFramesList{imageIndex} = movingDownTemp;
            % Refresh the list and such after the move:
            app.reloadExtractedFramesList();
            app.recomputeOutputImage();
        end
        
        % Removes the image at index imageIndex from the array
        function actionRemoveImageFrame(app, imageIndex)
            % app.pbeExtractedFramesList{imageIndex} = [];
            app.pbeExtractedFramesList(imageIndex) = [];
            % Refresh the list and such after the move:
            app.reloadExtractedFramesList();
            app.recomputeOutputImage();
            % Update the Interface:
            app.refreshInterfaceInteractability();
        end
        
        % Performs the task of exporting the specified extracted image index to the specified parent path, building a filename
        function actionExportImageFrame(app, imageIndex)
            
            exportPath = tryGetExtractedFramesExportPath(app, false);
            if isempty(exportPath)
                error('Export path is empty!!')
            end
            
            export_pbeExtractedFrame = app.pbeExtractedFramesList{imageIndex};
            exportImage = export_pbeExtractedFrame.image;
%             % curr_output_name is built iteratively, depending on what information is available
%             curr_output_name = 'extractedFrame';
%             hasUniqueId = false;
%             
%             % Frame Index:
%             if export_pbeExtractedFrame.frameIndex > -1
%                 curr_output_name = [curr_output_name, '_Frame_', num2str(export_pbeExtractedFrame.frameIndex)];
%                 hasUniqueId = true;
%             end
%             
%             % Source Name:
%             if ~isempty(export_pbeExtractedFrame.sourceName)
%                 curr_output_name = [curr_output_name, '_', export_pbeExtractedFrame.sourceName];    
%             end
%             
%             if ~hasUniqueId
%               [bbIDString] = app.bbvaVideoFile.boxID;
%                 curr_output_name = [curr_output_name, '_BB', bbIDString, '_i', num2str(imageIndex)];
%             end
            
            curr_output_name = makeExtractedFrameOutputName(export_pbeExtractedFrame, imageIndex, app.bbvaVideoFile.boxID);
            
            curr_output_name = [curr_output_name, '.bmp']; % Add Extension
            curr_output_path = fullfile(exportPath, curr_output_name);
            fprintf('Writing file out to %s... ', curr_output_path);
            imwrite(exportImage, curr_output_path, 'bmp'); % Write it out to file
            disp('done.\n')
            
        end
        
        function actionExportAllExtractedFramesToFolder(app)
            % Exports the currently extracted frames to a folder:
            for i=1:length(app.pbeExtractedFramesList)
                app.actionExportImageFrame(i);                 
            end
%             % Interactive Saving of image:
%             exportPath = uigetdir('../','Select an export directory for all the extracted frames');
%             user_canceled = (exportPath == 0);
%             if ~user_canceled
%                 fprintf('User chose export path: %s.\n', exportPath)
%                 for i=1:length(app.pbeExtractedFramesList)
%                     app.actionExportImageFrame(i);                 
%                 end
%             
%             else
%                 fprintf('User canceled\n')
%             end
        end
        
        
        % Exports the output image
        function [filename, user_canceled] = actionExportBackgroundImageAs(app)
            finalResultFrame = app.recomputeOutputImage();
            finalImageH = imshow(finalResultFrame);
            % Interactive Saving of image:
            [filename, user_canceled] = imsave(finalImageH); % Interactive image export.
            fprintf('Background Image written to %s.\n', filename)
        end
        
        
        % Prompts the user for an export path for the background extracted
        function actionInteractiveChangeExportPath(app)
            initialExportPath = '';
            if ~isempty(app.currentExportParentPath)
              initialExportPath = app.currentExportFileName;
            end
            
            if ~isempty(app.currentExportFileName)
                if ~isempty(initialExportPath)
                    initialExportPath = fullfile(initialExportPath, app.currentExportFileName);
                else
                    initialExportPath = app.currentExportFileName;
                end
            end
           
            if ~isempty(initialExportPath)
                [fileName, fileParentPath] = uiputfile('*.BMP','Background Export File Save Location', initialExportPath);
            else
                [fileName, fileParentPath] = uiputfile('*.BMP','Background Export File Save Location'); % Call it without the initial path.
            end
            drawnow;
            figure(app.PhoVideoBackgroundExtractorUIFigure)
            
            user_canceled = (fileName == 0);
            if ~user_canceled
                fprintf('User chose export file name: %s in parent path: %s.\n', fileName, fileParentPath)
                app.onChangeExportPath(fileParentPath, fileName)
            
            else
                fprintf('User canceled\n')
            end
            
        end
        
        
        % Exports the file to the pre-specified path.
        function currExportFullpath = actionExportBackground(app)
            currExportFullpath = fullfile(app.currentExportParentPath, app.currentExportFileName);
            finalResultFrame = app.recomputeOutputImage();
            imwrite(finalResultFrame, currExportFullpath,'bmp');
            fprintf('Background Image written to %s.\n', currExportFullpath)
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Perform initial setup:
            app.setup();
            
            
            % Parse the optional input arguments
            if nargin >= 2
                % Get the first argument as the video to be opened up:
                initialVideoToOpenPath = varargin{1};
                [fileParentPath, basename, fileExtension] = fileparts(initialVideoToOpenPath);
                fileName = [basename, fileExtension];
                app.onOpenSourceVideo(fileParentPath, fileName); % Call the initial loading function if passed in with an input argument.
            end
            
            if nargin >= 4
                % Get the second argument as the desired export output name/path
                initialExportParentPath = varargin{2};
                initialExportFilename = varargin{3};
                [testInputFileParentPath, testInputFileBasename, testInputFileExtension] = fileparts(initialExportFilename); % Make sure it's really a filename

                if isempty(testInputFileBasename)
                    error('Fatal issue with input filename!')
                end
                if ~isempty(testInputFileParentPath)
                    % Append to other?
                    error('Issue with input filename!')
                end
                if isempty(testInputFileExtension)
                    testInputFileExtension = '.bmp'; % Add extension to the name
                end
                
                initialExportFilename = [testInputFileBasename, testInputFileExtension]; % Concatenate with extension
                
                app.onChangeExportPath(initialExportParentPath, initialExportFilename);
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.recomputeOutputImage();
        end

        % Menu selected function: RefreshGeneratedBackgroundMenu
        function RefreshGeneratedBackgroundMenuSelected(app, event)
            app.recomputeOutputImage();
        end

        % Button pushed function: ExportAsButton
        function ExportAsButtonPushed(app, event)
            app.actionExportBackgroundImageAs();
        end

        % Menu selected function: ExportGeneratedBackgroundAsMenu
        function ExportGeneratedBackgroundAsMenuSelected(app, event)
            app.actionExportBackgroundImageAs();
        end

        % Menu selected function: ExportExtractedFramesMenu
        function ExportExtractedFramesMenuSelected(app, event)
            app.actionExportAllExtractedFramesToFolder();
        end

        % Menu selected function: ClearAllExtractedFramesMenu
        function ClearAllExtractedFramesMenuSelected(app, event)
            app.onLoadImageFrames({}) % Pass an empty list
        end

        % Value changed function: SliderPlayback
        function SliderPlaybackValueChanged(app, event)
            app.actionSeekVideoPosition();
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            app.actionInteractiveOpenVideo();
        end

        % Button pushed function: GrabButton
        function GrabButtonPushed(app, event)
            app.actionGrabCurrentVideoFrame();
        end

        % Menu selected function: OpenSourceVideoMenu
        function OpenSourceVideoMenuSelected(app, event)
            app.actionInteractiveOpenVideo();
        end

        % Button pushed function: DownButtonA
        function DownButtonAPushed(app, event)
            app.actionMoveImageDown(1);
        end

        % Button pushed function: DownButtonB
        function DownButtonBPushed(app, event)
            app.actionMoveImageDown(2);
        end

        % Button pushed function: DownButtonC
        function DownButtonCPushed(app, event)
            app.actionMoveImageDown(3);
        end

        % Button pushed function: UpButtonB
        function UpButtonBPushed(app, event)
            app.actionMoveImageUp(2);
        end

        % Button pushed function: UpButtonC
        function UpButtonCPushed(app, event)
            app.actionMoveImageUp(3);
        end

        % Button pushed function: UpButtonD
        function UpButtonDPushed(app, event)
            app.actionMoveImageUp(4);
        end

        % Button pushed function: RemoveButtonA
        function RemoveButtonAPushed(app, event)
            app.actionRemoveImageFrame(1);
        end

        % Button pushed function: RemoveButtonB
        function RemoveButtonBPushed(app, event)
            app.actionRemoveImageFrame(2);
        end

        % Button pushed function: RemoveButtonC
        function RemoveButtonCPushed(app, event)
            app.actionRemoveImageFrame(3);
        end

        % Button pushed function: RemoveButtonD
        function RemoveButtonDPushed(app, event)
            app.actionRemoveImageFrame(4);
        end

        % Button pushed function: ExportButtonA
        function ExportButtonAPushed(app, event)
            app.actionExportImageFrame(1);
        end

        % Button pushed function: ExportButtonB
        function ExportButtonBPushed(app, event)
            app.actionExportImageFrame(2);
        end

        % Button pushed function: ExportButtonC
        function ExportButtonCPushed(app, event)
            app.actionExportImageFrame(3);
        end

        % Button pushed function: ExportButtonD
        function ExportButtonDPushed(app, event)
            app.actionExportImageFrame(4);
        end

        % Button pushed function: ChooseExportPathButton
        function ChooseExportPathButtonPushed(app, event)
            app.actionInteractiveChangeExportPath();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.actionExportBackground();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PhoVideoBackgroundExtractorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {631, 631, 631};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {631, 631};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x', 282};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhoVideoBackgroundExtractorUIFigure = uifigure('Visible', 'off');
            app.PhoVideoBackgroundExtractorUIFigure.Name = 'Pho Video Background Extractor';
            app.PhoVideoBackgroundExtractorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.PhoVideoBackgroundExtractorUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenSourceVideoMenu = uimenu(app.FileMenu);
            app.OpenSourceVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSourceVideoMenuSelected, true);
            app.OpenSourceVideoMenu.Text = 'Open Source Video...';
            app.ExportExtractedFramesMenu = uimenu(app.FileMenu);
            app.ExportExtractedFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportExtractedFramesMenuSelected, true);
            app.ExportExtractedFramesMenu.Text = 'Export Extracted Frames';
            app.ExportGeneratedBackgroundAsMenu = uimenu(app.FileMenu);
            app.ExportGeneratedBackgroundAsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportGeneratedBackgroundAsMenuSelected, true);
            app.ExportGeneratedBackgroundAsMenu.Text = 'Export Generated Background As..';
            app.EditMenu = uimenu(app.PhoVideoBackgroundExtractorUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ClearAllExtractedFramesMenu = uimenu(app.EditMenu);
            app.ClearAllExtractedFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllExtractedFramesMenuSelected, true);
            app.ClearAllExtractedFramesMenu.Text = 'Clear All Extracted Frames';
            app.RefreshGeneratedBackgroundMenu = uimenu(app.EditMenu);
            app.RefreshGeneratedBackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshGeneratedBackgroundMenuSelected, true);
            app.RefreshGeneratedBackgroundMenu.Text = 'Refresh Generated Background';
            app.GridLayout = uigridlayout(app.PhoVideoBackgroundExtractorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Source Video';
            app.Image = uiimage(app.LeftPanel);
            app.Image.Enable = 'off';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = '';
            app.SliderPlayback = uislider(app.LeftPanel);
            app.SliderPlayback.Limits = [1 100];
            app.SliderPlayback.ValueChangedFcn = createCallbackFcn(app, @SliderPlaybackValueChanged, true);
            app.SliderPlayback.Enable = 'off';
            app.SliderPlayback.Value = 1;
            app.GrabButton = uibutton(app.LeftPanel, 'push');
            app.GrabButton.ButtonPushedFcn = createCallbackFcn(app, @GrabButtonPushed, true);
            app.GrabButton.Enable = 'off';
            app.GrabButton.Text = 'Grab >>';
            app.PlayPauseButton = uibutton(app.LeftPanel, 'state');
            app.PlayPauseButton.Enable = 'off';
            app.PlayPauseButton.Visible = 'off';
            app.PlayPauseButton.Text = 'Play/Pause';
            app.InstructionsTextAreaLabel = uilabel(app.LeftPanel);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.LeftPanel);
            app.InstructionsTextArea.Editable = 'off';
            app.InstructionsTextArea.Value = {'From the loaded source video, navigate to frames that contain the mouse in non-overlapping positions. Press the "Grab >>" button to add the current frame to the list of extracted frames.'; ''; 'The goal is to find two frames that are as similar as possible except in the position of the mouse, which are as non-overlapping as possible.'};
            app.ChooseButton = uibutton(app.LeftPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose...';
            app.OpenedVideoLabel = uilabel(app.LeftPanel);
            app.OpenedVideoLabel.Text = 'No Opened Video';
            app.NoSourceVideoLabel = uilabel(app.LeftPanel);
            app.NoSourceVideoLabel.Text = 'No Source Video';
            app.STATIC_TotalFramesLabel = uilabel(app.LeftPanel);
            app.STATIC_TotalFramesLabel.Text = 'Total Frames:';
            app.CurrFrameEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CurrFrameEditField.Limits = [0 Inf];
            app.CurrFrameEditField.RoundFractionalValues = 'on';
            app.CurrFrameEditField.ValueDisplayFormat = '%d';
            app.CurrFrameEditField.Editable = 'off';
            app.CurrFrameEditField.Tooltip = {'Current Video Frame'};
            app.TotalFramesLabel = uilabel(app.LeftPanel);
            app.TotalFramesLabel.Text = 'No Video';
            app.STATIC_currFrameLabel = uilabel(app.LeftPanel);
            app.STATIC_currFrameLabel.Text = 'Frame:';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ExtractedFramesPanel = uipanel(app.CenterPanel);
            app.ExtractedFramesPanel.Title = 'Extracted Frames';
            app.GridLayoutExtractedFrames = uigridlayout(app.ExtractedFramesPanel);
            app.ExtractedImageA = uiimage(app.GridLayoutExtractedFrames);
            app.ExtractedImageA.Visible = 'off';
            app.ExtractedImageB = uiimage(app.GridLayoutExtractedFrames);
            app.ExtractedImageB.Visible = 'off';
            app.ExtractedImageC = uiimage(app.GridLayoutExtractedFrames);
            app.ExtractedImageC.Visible = 'off';
            app.ExtractedImageD = uiimage(app.GridLayoutExtractedFrames);
            app.ExtractedImageD.Visible = 'off';
            app.FrameOptionsPanelA = uipanel(app.GridLayoutExtractedFrames);
            app.FrameOptionsPanelA.Title = 'Frame A Options';
            app.FrameOptionsPanelA.Visible = 'off';
            app.GridLayoutA = uigridlayout(app.FrameOptionsPanelA);
            app.UpButtonA = uibutton(app.GridLayoutA, 'push');
            app.UpButtonA.Enable = 'off';
            app.UpButtonA.Visible = 'off';
            app.UpButtonA.Tooltip = {'Move frame up'};
            app.UpButtonA.Text = 'Up';
            app.DownButtonA = uibutton(app.GridLayoutA, 'push');
            app.DownButtonA.ButtonPushedFcn = createCallbackFcn(app, @DownButtonAPushed, true);
            app.DownButtonA.Enable = 'off';
            app.DownButtonA.Tooltip = {'Move frame down'};
            app.DownButtonA.Text = 'Down';
            app.RemoveButtonA = uibutton(app.GridLayoutA, 'push');
            app.RemoveButtonA.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonAPushed, true);
            app.RemoveButtonA.Tooltip = {'Remove Frame'};
            app.RemoveButtonA.Text = 'Remove';
            app.ExportButtonA = uibutton(app.GridLayoutA, 'push');
            app.ExportButtonA.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonAPushed, true);
            app.ExportButtonA.Tooltip = {'Export Frame'};
            app.ExportButtonA.Text = 'Export';
            app.STATIC_FrameLabelA = uilabel(app.GridLayoutA);
            app.STATIC_FrameLabelA.Text = 'Frame';
            app.FrameLabelA = uilabel(app.GridLayoutA);
            app.FrameLabelA.Text = '--';
            app.STATIC_SourceLabelA = uilabel(app.GridLayoutA);
            app.STATIC_SourceLabelA.Text = 'Source';
            app.SourceLabelA = uilabel(app.GridLayoutA);
            app.SourceLabelA.Text = '(Hover for Info)';
            app.FrameOptionsPanelB = uipanel(app.GridLayoutExtractedFrames);
            app.FrameOptionsPanelB.Title = 'Frame B Options';
            app.FrameOptionsPanelB.Visible = 'off';
            app.GridLayoutB = uigridlayout(app.FrameOptionsPanelB);
            app.UpButtonB = uibutton(app.GridLayoutB, 'push');
            app.UpButtonB.ButtonPushedFcn = createCallbackFcn(app, @UpButtonBPushed, true);
            app.UpButtonB.Enable = 'off';
            app.UpButtonB.Tooltip = {'Move frame up'};
            app.UpButtonB.Text = 'Up';
            app.DownButtonB = uibutton(app.GridLayoutB, 'push');
            app.DownButtonB.ButtonPushedFcn = createCallbackFcn(app, @DownButtonBPushed, true);
            app.DownButtonB.Enable = 'off';
            app.DownButtonB.Tooltip = {'Move frame down'};
            app.DownButtonB.Text = 'Down';
            app.RemoveButtonB = uibutton(app.GridLayoutB, 'push');
            app.RemoveButtonB.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonBPushed, true);
            app.RemoveButtonB.Tooltip = {'Remove Frame'};
            app.RemoveButtonB.Text = 'Remove';
            app.ExportButtonB = uibutton(app.GridLayoutB, 'push');
            app.ExportButtonB.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonBPushed, true);
            app.ExportButtonB.Tooltip = {'Export Frame'};
            app.ExportButtonB.Text = 'Export';
            app.STATIC_FrameLabelB = uilabel(app.GridLayoutB);
            app.STATIC_FrameLabelB.Text = 'Frame';
            app.FrameLabelB = uilabel(app.GridLayoutB);
            app.FrameLabelB.Text = '--';
            app.STATIC_SourceLabelB = uilabel(app.GridLayoutB);
            app.STATIC_SourceLabelB.Text = 'Source';
            app.SourceLabelB = uilabel(app.GridLayoutB);
            app.SourceLabelB.Text = '(Hover for Info)';
            app.FrameOptionsPanelC = uipanel(app.GridLayoutExtractedFrames);
            app.FrameOptionsPanelC.Title = 'Frame C Options';
            app.FrameOptionsPanelC.Visible = 'off';
            app.GridLayoutC = uigridlayout(app.FrameOptionsPanelC);
            app.UpButtonC = uibutton(app.GridLayoutC, 'push');
            app.UpButtonC.ButtonPushedFcn = createCallbackFcn(app, @UpButtonCPushed, true);
            app.UpButtonC.Enable = 'off';
            app.UpButtonC.Tooltip = {'Move frame up'};
            app.UpButtonC.Text = 'Up';
            app.DownButtonC = uibutton(app.GridLayoutC, 'push');
            app.DownButtonC.ButtonPushedFcn = createCallbackFcn(app, @DownButtonCPushed, true);
            app.DownButtonC.Enable = 'off';
            app.DownButtonC.Tooltip = {'Move frame down'};
            app.DownButtonC.Text = 'Down';
            app.RemoveButtonC = uibutton(app.GridLayoutC, 'push');
            app.RemoveButtonC.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonCPushed, true);
            app.RemoveButtonC.Tooltip = {'Remove Frame'};
            app.RemoveButtonC.Text = 'Remove';
            app.ExportButtonC = uibutton(app.GridLayoutC, 'push');
            app.ExportButtonC.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonCPushed, true);
            app.ExportButtonC.Tooltip = {'Export Frame'};
            app.ExportButtonC.Text = 'Export';
            app.STATIC_FrameLabelC = uilabel(app.GridLayoutC);
            app.STATIC_FrameLabelC.Text = 'Frame';
            app.FrameLabelC = uilabel(app.GridLayoutC);
            app.FrameLabelC.Text = '--';
            app.STATIC_SourceLabelC = uilabel(app.GridLayoutC);
            app.STATIC_SourceLabelC.Text = 'Source';
            app.SourceLabelC = uilabel(app.GridLayoutC);
            app.SourceLabelC.Text = '(Hover for Info)';
            app.FrameOptionsPanelD = uipanel(app.GridLayoutExtractedFrames);
            app.FrameOptionsPanelD.Title = 'Frame D Options';
            app.FrameOptionsPanelD.Visible = 'off';
            app.GridLayoutD = uigridlayout(app.FrameOptionsPanelD);
            app.UpButtonD = uibutton(app.GridLayoutD, 'push');
            app.UpButtonD.ButtonPushedFcn = createCallbackFcn(app, @UpButtonDPushed, true);
            app.UpButtonD.Enable = 'off';
            app.UpButtonD.Tooltip = {'Move frame up'};
            app.UpButtonD.Text = 'Up';
            app.DownButtonD = uibutton(app.GridLayoutD, 'push');
            app.DownButtonD.Enable = 'off';
            app.DownButtonD.Visible = 'off';
            app.DownButtonD.Tooltip = {'Move frame down'};
            app.DownButtonD.Text = 'Down';
            app.RemoveButtonD = uibutton(app.GridLayoutD, 'push');
            app.RemoveButtonD.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonDPushed, true);
            app.RemoveButtonD.Tooltip = {'Remove Frame'};
            app.RemoveButtonD.Text = 'Remove';
            app.ExportButtonD = uibutton(app.GridLayoutD, 'push');
            app.ExportButtonD.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonDPushed, true);
            app.ExportButtonD.Tooltip = {'Export Frame'};
            app.ExportButtonD.Text = 'Export';
            app.STATIC_FrameLabelD = uilabel(app.GridLayoutD);
            app.STATIC_FrameLabelD.Text = 'Frame';
            app.FrameLabelD = uilabel(app.GridLayoutD);
            app.FrameLabelD.Text = '--';
            app.STATIC_SourceLabelD = uilabel(app.GridLayoutD);
            app.STATIC_SourceLabelD.Text = 'Source';
            app.SourceLabelD = uilabel(app.GridLayoutD);
            app.SourceLabelD.Text = '(Hover for Info)';
            app.LabelNoSelectedFrames = uilabel(app.CenterPanel);
            app.LabelNoSelectedFrames.Text = {'No selected frames.'; 'With a video loaded, click the "grab" button to add a selected frame.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Generated Background';
            app.ImageFinalBackground = uiimage(app.RightPanel);
            app.ImageFinalBackground.Enable = 'off';
            app.RefreshButton = uibutton(app.RightPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ExportAsButton = uibutton(app.RightPanel, 'push');
            app.ExportAsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAsButtonPushed, true);
            app.ExportAsButton.Text = 'Export As...';
            app.BackgroundExportPathTextAreaLabel = uilabel(app.RightPanel);
            app.BackgroundExportPathTextAreaLabel.Text = 'Background Export Path';
            app.BackgroundExportPathTextArea = uitextarea(app.RightPanel);
            app.BackgroundExportPathTextArea.Editable = 'off';
            app.ChooseExportPathButton = uibutton(app.RightPanel, 'push');
            app.ChooseExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExportPathButtonPushed, true);
            app.ChooseExportPathButton.Text = 'Choose...';
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.PhoVideoBackgroundExtractorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhoPendingBackgroundExtractor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhoVideoBackgroundExtractorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhoVideoBackgroundExtractorUIFigure)
        end
    end
end